classdef paramsSettingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout3              matlab.ui.container.GridLayout
        ParameterssettingsLabel  matlab.ui.control.Label
        CancelButton             matlab.ui.control.Button
        OKButton                 matlab.ui.control.Button
        TriggerPanel             matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        comEditField             matlab.ui.control.NumericEditField
        Label_13                 matlab.ui.control.Label
        triggerTypeDropDown      matlab.ui.control.DropDown
        Label_12                 matlab.ui.control.Label
        addressEditField         matlab.ui.control.NumericEditField
        Label_11                 matlab.ui.control.Label
        StimulusPanel            matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        volumnEditField          matlab.ui.control.NumericEditField
        VolumnLabel              matlab.ui.control.Label
        nRepeatEditField         matlab.ui.control.NumericEditField
        Label_8                  matlab.ui.control.Label
        forceCheckBox            matlab.ui.control.CheckBox
        AdvancedsettingsButton   matlab.ui.control.Button
        fsEditField              matlab.ui.control.NumericEditField
        Label_9                  matlab.ui.control.Label
        kHzLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        app_parent
        params
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_parent, params)
            app.app_parent = app_parent;

            app.UIFigure.Name = 'Parameter settings';
            
            if ~isempty(params)
                try
                    app.params                    = params;
                    app.nRepeatEditField.Value    = params.nRepeat;
                    app.fsEditField.Value         = params.fs / 1e3; % kHz
                    app.volumnEditField.Value     = params.volumn;
                    app.triggerTypeDropDown.Value = params.triggerType;
                    app.addressEditField.Value    = params.address;
                    app.comEditField.Value        = params.COM;
                    app.forceCheckBox.Value       = params.useSettingnRepeat;
                catch ME
                    throw(ME);
                end
            end

            if strcmpi(params.triggerType, 'LTP')
                app.addressEditField.Enable = 'on';
            else
                app.addressEditField.Enable = 'off';
            end

            if strcmpi(params.triggerType, 'COM')
                app.comEditField.Enable = 'on';
            else
                app.comEditField.Enable = 'off';
            end

            movegui(app.UIFigure, "center")
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.params.nRepeat           = app.nRepeatEditField.Value;
            app.params.fs                = app.fsEditField.Value * 1e3; % Hz
            app.params.volumn            = app.volumnEditField.Value;
            app.params.triggerType       = app.triggerTypeDropDown.Value;
            app.params.address           = app.addressEditField.Value;
            app.params.COM               = app.comEditField.Value;
            app.params.useSettingnRepeat = app.forceCheckBox.Value;
            app.app_parent.params = app.params;
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: triggerTypeDropDown
        function triggerTypeDropDownValueChanged(app, event)
            value = app.triggerTypeDropDown.Value;
            if strcmpi(value, 'LTP')
                app.addressEditField.Enable = 'on';
            else
                app.addressEditField.Enable = 'off';
            end
            if strcmpi(value, 'COM')
                app.comEditField.Enable = 'on';
            else
                app.comEditField.Enable = 'off';
            end
        end

        % Button pushed function: AdvancedsettingsButton
        function AdvancedsettingsButtonPushed(app, event)
            if isfield("Evts", app.params) && ~isempty(app.params.Evts)
                preset = app.params.Evts;
            else
                currentFolder = fileparts(mfilename("fullpath"));
                preset = fullfile(currentFolder, "config", "eventFlow_passive.mat");
            end
            eventFlow = eventFlowApp(preset);
            uiwait(eventFlow.UIFigure);
            if isvalid(eventFlow)
                app.params.Evts = eventFlow.Evts;
                delete(eventFlow);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Parameter settings';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout3 = uigridlayout(app.UIFigure);
            app.StimulusPanel = uipanel(app.GridLayout3);
            app.StimulusPanel.Title = 'Stimulus';
            app.GridLayout2 = uigridlayout(app.StimulusPanel);
            app.kHzLabel = uilabel(app.GridLayout2);
            app.kHzLabel.Text = 'kHz';
            app.Label_9 = uilabel(app.GridLayout2);
            app.Label_9.Text = 'Device sample rate';
            app.fsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.fsEditField.Limits = [0 Inf];
            app.fsEditField.Value = 384;
            app.AdvancedsettingsButton = uibutton(app.GridLayout2, 'push');
            app.AdvancedsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedsettingsButtonPushed, true);
            app.AdvancedsettingsButton.Text = 'Advanced settings';
            app.forceCheckBox = uicheckbox(app.GridLayout2);
            app.forceCheckBox.Text = 'force';
            app.Label_8 = uilabel(app.GridLayout2);
            app.Label_8.Text = 'Trial repeats';
            app.nRepeatEditField = uieditfield(app.GridLayout2, 'numeric');
            app.nRepeatEditField.Limits = [0 Inf];
            app.nRepeatEditField.Value = 40;
            app.VolumnLabel = uilabel(app.GridLayout2);
            app.VolumnLabel.Text = 'Volumn';
            app.volumnEditField = uieditfield(app.GridLayout2, 'numeric');
            app.volumnEditField.Limits = [0 1];
            app.volumnEditField.Value = 0.3;
            app.TriggerPanel = uipanel(app.GridLayout3);
            app.TriggerPanel.Title = 'Trigger';
            app.GridLayout = uigridlayout(app.TriggerPanel);
            app.Label_11 = uilabel(app.GridLayout);
            app.Label_11.Text = 'LTP Port';
            app.addressEditField = uieditfield(app.GridLayout, 'numeric');
            app.addressEditField.Limits = [0 Inf];
            app.addressEditField.Value = 888;
            app.Label_12 = uilabel(app.GridLayout);
            app.Label_12.Text = 'Trigger';
            app.triggerTypeDropDown = uidropdown(app.GridLayout);
            app.triggerTypeDropDown.Items = {'None', 'LTP', 'COM', 'TriggerBox'};
            app.triggerTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @triggerTypeDropDownValueChanged, true);
            app.triggerTypeDropDown.Value = 'None';
            app.Label_13 = uilabel(app.GridLayout);
            app.Label_13.Text = 'COM Port';
            app.comEditField = uieditfield(app.GridLayout, 'numeric');
            app.comEditField.Limits = [0 Inf];
            app.comEditField.ValueDisplayFormat = '%.0f';
            app.comEditField.Value = 1;
            app.OKButton = uibutton(app.GridLayout3, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Tag = 'confirmButton';
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.GridLayout3, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ParameterssettingsLabel = uilabel(app.GridLayout3);
            app.ParameterssettingsLabel.Text = 'Parameters settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = paramsSettingApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end