classdef subjectInfoInputApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        CancelButton        matlab.ui.control.Button
        ConfirmButton       matlab.ui.control.Button
        SubjectInfoPanel    matlab.ui.container.Panel
        CommentEditField    matlab.ui.control.TextArea
        DiagnosisEditField  matlab.ui.control.TextArea
        ContactEditField    matlab.ui.control.EditField
        ProEditField        matlab.ui.control.EditField
        BirthDatePicker     matlab.ui.control.DatePicker
        GenderDropDown      matlab.ui.control.DropDown
        NameEditField       matlab.ui.control.EditField
        IDEditField         matlab.ui.control.EditField
        Label_8             matlab.ui.control.Label
        Label_7             matlab.ui.control.Label
        IDLabel             matlab.ui.control.Label
        Label_6             matlab.ui.control.Label
        Label_5             matlab.ui.control.Label
        Label_4             matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        Label               matlab.ui.control.Label
    end

    
    properties (Access = private)
        app_parent
        subjectInfo
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_parent, subjectInfo)
            app.UIFigure.Name = '听觉任务刺激系统';
            srceenSize = get(0, "screensize");
            figSize = app.UIFigure.Position;
            figSize(1) = (srceenSize(3) - figSize(3)) / 2;
            figSize(2) = (srceenSize(4) - figSize(4)) / 2;
            app.UIFigure.Position = figSize;

            app.app_parent = app_parent;

            try
                app.subjectInfo = subjectInfo;
                app.IDEditField.Value = subjectInfo.ID;
                app.NameEditField.Value = subjectInfo.name;
                app.GenderDropDown.Value = subjectInfo.gender;
                app.BirthDatePicker.Value = subjectInfo.birthDate;
                app.ProEditField.Value = subjectInfo.profession;
                app.CommentEditField.Value = subjectInfo.contact;
                app.DiagnosisEditField.Value = subjectInfo.diagnosis;
                app.CommentEditField.Value = subjectInfo.comment;
            end
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.subjectInfo.ID = app.IDEditField.Value;
            app.subjectInfo.name = app.NameEditField.Value;
            app.subjectInfo.gender = app.GenderDropDown.Value;
            app.subjectInfo.birthDate = app.BirthDatePicker.Value;
            app.subjectInfo.profession = app.ProEditField.Value;
            app.subjectInfo.contact = app.CommentEditField.Value;
            app.subjectInfo.diagnosis = app.DiagnosisEditField.Value;
            app.subjectInfo.comment = app.CommentEditField.Value;

            % Validate
            if isempty(app.subjectInfo.ID) || isempty(app.subjectInfo.name) || strcmp(app.subjectInfo.gender, '-请选择-')
                Msgbox('带*的为必填项!', 'Info', 'center');
            else
                app.app_parent.subjectInfo = app.subjectInfo;

                if ~isempty(app.subjectInfo)
                    app.app_parent.StartButton.Enable = 'on';
                end

                delete(app);
            end

        end

        % Callback function: CancelButton, GenderDropDown
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SubjectInfoPanel = uipanel(app.UIFigure);
            app.SubjectInfoPanel.Title = '被试基本信息(*必填)';
            app.Label = uilabel(app.SubjectInfoPanel);
            app.Label.Text = '*性别(Gender)';
            app.Label_2 = uilabel(app.SubjectInfoPanel);
            app.Label_2.Text = '*出生日期(Birth)';
            app.Label_4 = uilabel(app.SubjectInfoPanel);
            app.Label_4.Text = '*姓名(Name)';
            app.Label_5 = uilabel(app.SubjectInfoPanel);
            app.Label_5.Text = '职业(Profession)';
            app.Label_6 = uilabel(app.SubjectInfoPanel);
            app.Label_6.Text = '联系方式(Contact)';
            app.IDLabel = uilabel(app.SubjectInfoPanel);
            app.IDLabel.Text = '*被试编号(ID)';
            app.Label_7 = uilabel(app.SubjectInfoPanel);
            app.Label_7.Text = {'病史(Diagnosis)'; ''};
            app.Label_8 = uilabel(app.SubjectInfoPanel);
            app.Label_8.Text = '备注(Comment)';
            app.IDEditField = uieditfield(app.SubjectInfoPanel, 'text');
            app.IDEditField.Tag = 'subjectID';
            app.NameEditField = uieditfield(app.SubjectInfoPanel, 'text');
            app.NameEditField.Tag = 'subjectName';
            app.GenderDropDown = uidropdown(app.SubjectInfoPanel);
            app.GenderDropDown.Items = {'-请选择-', '男', '女'};
            app.GenderDropDown.ValueChangedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.GenderDropDown.Tag = 'subjectGender';
            app.GenderDropDown.Value = '-请选择-';
            app.BirthDatePicker = uidatepicker(app.SubjectInfoPanel);
            app.BirthDatePicker.DisplayFormat = 'uuuu年 MM月 dd日';
            app.BirthDatePicker.Editable = 'off';
            app.BirthDatePicker.Tag = 'subjectBirthDate';
            app.BirthDatePicker.Value = datetime([1990 1 1]);
            app.ProEditField = uieditfield(app.SubjectInfoPanel, 'text');
            app.ProEditField.Tag = 'subjectProfession';
            app.ContactEditField = uieditfield(app.SubjectInfoPanel, 'text');
            app.ContactEditField.Tag = 'subjectContact';
            app.ContactEditField.Placeholder = '电话phone number/邮箱email';
            app.DiagnosisEditField = uitextarea(app.SubjectInfoPanel);
            app.DiagnosisEditField.Tag = 'subjectDiagnosis';
            app.DiagnosisEditField.Placeholder = '请以英文逗号分隔separate with comma';
            app.CommentEditField = uitextarea(app.SubjectInfoPanel);
            app.CommentEditField.Tag = 'comment';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Tag = 'confirmButton';
            app.ConfirmButton.Text = '确认';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = '取消';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = subjectInfoInputApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end